classdef PropulsionApp_AlphaVersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MenuTab                         matlab.ui.container.Tab
        IworkedhardLabel                matlab.ui.control.Label
        YesLabel                        matlab.ui.control.Label
        WorkingLabel                    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DogButton                       matlab.ui.control.Button
        NozzleButton                    matlab.ui.control.Button
        PropulsionAppLabel              matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        PlotTab                         matlab.ui.container.Tab
        PlotsLabel                      matlab.ui.control.Label
        MinValueEditField               matlab.ui.control.NumericEditField
        MinValueEditFieldLabel          matlab.ui.control.Label
        MaxValueEditField               matlab.ui.control.NumericEditField
        MaxValueEditFieldLabel          matlab.ui.control.Label
        YValueEditField                 matlab.ui.control.NumericEditField
        YValueEditFieldLabel            matlab.ui.control.Label
        XValueEditField                 matlab.ui.control.NumericEditField
        XValueEditFieldLabel            matlab.ui.control.Label
        DefaultBoundsCheckBox           matlab.ui.control.CheckBox
        PlotButton_2                    matlab.ui.control.Button
        PlottedValueDropDown            matlab.ui.control.DropDown
        PlottedValueDropDownLabel       matlab.ui.control.Label
        UpperYBoundEditField            matlab.ui.control.NumericEditField
        UpperYBoundEditFieldLabel       matlab.ui.control.Label
        UpperXBoundEditField            matlab.ui.control.NumericEditField
        UpperXBoundEditFieldLabel       matlab.ui.control.Label
        csvFileEditField                matlab.ui.control.EditField
        csvFileEditFieldLabel           matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        LowerYBoundEditField            matlab.ui.control.NumericEditField
        LowerYBoundEditFieldLabel       matlab.ui.control.Label
        LowerXBoundEditField            matlab.ui.control.NumericEditField
        LowerXBoundEditFieldLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        NozzleTab                       matlab.ui.container.Tab
        ExpansionRatioEditField         matlab.ui.control.EditField
        ExpansionRatioEditFieldLabel    matlab.ui.control.Label
        CalculateExpansionRatioButton   matlab.ui.control.StateButton
        MachNumberatExitEditField       matlab.ui.control.NumericEditField
        MachNumberatExitEditFieldLabel  matlab.ui.control.Label
        ExitAream2EditField             matlab.ui.control.EditField
        ExitAream2EditFieldLabel        matlab.ui.control.Label
        ChokeAream2EditField            matlab.ui.control.EditField
        ChokeAream2EditFieldLabel       matlab.ui.control.Label
        CalculateAreasButton            matlab.ui.control.StateButton
        OutputLabel                     matlab.ui.control.Label
        NozzleCalculationsLabel         matlab.ui.control.Label
        HeatCapacityRatioGammaEditField  matlab.ui.control.NumericEditField
        HeatCapacityRatioGammaEditFieldLabel  matlab.ui.control.Label
        ThroatAirPressurePaEditField    matlab.ui.control.NumericEditField
        ThroatAirPressurePaEditFieldLabel  matlab.ui.control.Label
        StandardAirPressurePaEditField  matlab.ui.control.NumericEditField
        StandardAirPressurePaEditFieldLabel  matlab.ui.control.Label
        OptimalThrustNEditField         matlab.ui.control.NumericEditField
        OptimalThrustNEditFieldLabel    matlab.ui.control.Label
        DogTab                          matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        NumberofRocketGrainsEditField   matlab.ui.control.NumericEditField
        NumberofRocketGrainsEditFieldLabel  matlab.ui.control.Label
        HeightofChargeWellEditField     matlab.ui.control.NumericEditField
        HeightofChargeWellEditFieldLabel  matlab.ui.control.Label
        RadiusofChargeWellEditField     matlab.ui.control.NumericEditField
        RadiusofChargeWellEditFieldLabel  matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        PLEASELabel                     matlab.ui.control.Label
        Button2                         matlab.ui.control.Button
        DatePicker                      matlab.ui.control.DatePicker
        DatePickerLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        xl = [0,1]; % changing bounds
        yl = [0,1];
        xldefault = [0,1];%default bounds
        yldefault = [0,1];
        plotValue = 'Thrust(N)';
        defaultBounds = 0;
        userX = 0;
        userY = 0;
        userData = {0,0};
        outputY = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            userTitle = app.TitleEditField.Value;
            title(app.UIAxes,userTitle);
        end

        % Value changed function: csvFileEditField
        function csvFileEditFieldValueChanged(app, event)
            app.userData = readtable(app.csvFileEditField.Value);
        end

        % Value changed function: UpperXBoundEditField
        function UpperXBoundEditFieldValueChanged(app, event)
            userUpXbound = app.UpperXBoundEditField.Value;
            app.xl(2) = userUpXbound;
        end

        % Value changed function: LowerXBoundEditField
        function LowerXBoundEditFieldValueChanged(app, event)
            userLoXbound = app.LowerXBoundEditField.Value;
            app.xl(1) = userLoXbound;
        end

        % Value changed function: LowerYBoundEditField
        function LowerYBoundEditFieldValueChanged(app, event)
            userLoYbound = app.LowerYBoundEditField.Value;
            app.yl(1) = userLoYbound;
        end

        % Value changed function: UpperYBoundEditField
        function UpperYBoundEditFieldValueChanged(app, event)
            userUpYbound = app.UpperYBoundEditField.Value;
            app.yl(2) = userUpYbound;
        end

        % Callback function
        function DefaultBoundsButtonPushed(app, event)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PlotTab;
        end

        % Value changed function: PlottedValueDropDown
        function PlottedValueDropDownValueChanged(app, event)
            app.plotValue = app.PlottedValueDropDown.Value;
            
        end

        % Value changed function: DefaultBoundsCheckBox
        function DefaultBoundsCheckBoxValueChanged(app, event)
            app.defaultBounds = app.DefaultBoundsCheckBox.Value;
            
            if app.defaultBounds == 1
                app.LowerXBoundEditFieldLabel.Visible = 'off';
                app.LowerYBoundEditFieldLabel.Visible = 'off';
                app.UpperXBoundEditFieldLabel.Visible = 'off';
                app.UpperYBoundEditFieldLabel.Visible = 'off';
                
                app.LowerXBoundEditField.Visible = 'off';
                app.LowerYBoundEditField.Visible = 'off';
                app.UpperXBoundEditField.Visible = 'off';
                app.UpperYBoundEditField.Visible = 'off';
               
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                
                
            else
                app.LowerXBoundEditFieldLabel.Visible = 'on';
                app.LowerYBoundEditFieldLabel.Visible = 'on';
                app.UpperXBoundEditFieldLabel.Visible = 'on';
                app.UpperYBoundEditFieldLabel.Visible = 'on';
                
                app.LowerXBoundEditField.Visible = 'on';
                app.LowerYBoundEditField.Visible = 'on';
                app.UpperXBoundEditField.Visible = 'on';
                app.UpperYBoundEditField.Visible = 'on';
                
                app.UIAxes.XLimMode = 'manual';
                app.UIAxes.YLimMode = 'manual';
            end
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            
            if app.defaultBounds == 1
                app.UIAxes.XLim = [-inf inf];
                app.UIAxes.YLim = [-inf inf];

            else
                app.UIAxes.XLimMode = 'manual';
                app.UIAxes.YLimMode = 'manual';
                app.UIAxes.XLim = app.xl;
                app.UIAxes.YLim = app.yl;

            end
            csvChoice = app.plotValue;
            switch csvChoice
                case "Thrust (kN)"
                        app.userX = app.userData{:, 1}; %time
                        app.userY = app.userData{:, 4}*4.448*10^-3; %thrust N
                    
                case "Thrust (lbf)"
                        app.userX = app.userData{:, 1}; %time
                        app.userY = app.userData{:, 4}; %thrust lbf

                otherwise

            end
            plot(app.UIAxes,app.userX,app.userY);
            ylabel(app.UIAxes, csvChoice);
            xlabel(app.UIAxes, 'Time (s)');
            app.MaxValueEditField.Value = max(app.userY);
            app.MinValueEditField.Value = min(app.userY);
        end

        % Value changed function: XValueEditField
        function XValueEditFieldValueChanged(app, event)
            inputX = app.XValueEditField.Value;
            app.outputY = find(app.userX == inputX);
            app.YValueEditField.Value = app.userY(app.outputY);
        end

        % Callback function
        function InputEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: NozzleButton
        function NozzleButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.NozzleTab;
        end

        % Button pushed function: DogButton
        function DogButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DogTab;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)


        end

        % Value changed function: CalculateAreasButton
        function CalculateAreasButtonValueChanged(app, event)
            opThrust = app.OptimalThrustNEditField.Value;
            stAirPres = app.StandardAirPressurePaEditField.Value;
            thAirPres = app.ThroatAirPressurePaEditField.Value;
            heCapRat = app.HeatCapacityRatioGammaEditField.Value;
            a_in = opThrust/(heCapRat*thAirPres*sqrt((2/(heCapRat-1))*((2/(heCapRat+1))^((heCapRat+1)/(heCapRat-1)))*(1-(stAirPres/thAirPres)^((heCapRat-1)/heCapRat))));
            a_out = (sqrt(((2/(heCapRat+1))^((heCapRat+1)/(heCapRat-1)))/(2/(heCapRat-1)))*sqrt(((thAirPres/stAirPres)^((heCapRat+1)/heCapRat))/(((thAirPres/stAirPres)^((heCapRat-1)/heCapRat))-1)));
            app.ChokeAream2EditField.Value = num2str(a_in);
            app.ExitAream2EditField.Value = num2str(a_out);
        end

        % Value changed function: CalculateExpansionRatioButton
        function CalculateExpansionRatioButtonValueChanged(app, event)
            heCapRat = app.HeatCapacityRatioGammaEditField.Value;
            mach = app.MachNumberatExitEditField.Value;
            ratio = ((1/mach)*((2/(heCapRat+1))*(1+((heCapRat-1)/2)*(mach^2))^((heCapRat+1)/(2*heCapRat-2))));
            app.ExpansionRatioEditField.Value = num2str(ratio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.PlotButton = uibutton(app.MenuTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PropulsionAppLabel = uilabel(app.MenuTab);
            app.PropulsionAppLabel.Text = 'Propulsion App';
            app.NozzleButton = uibutton(app.MenuTab, 'push');
            app.NozzleButton.ButtonPushedFcn = createCallbackFcn(app, @NozzleButtonPushed, true);
            app.NozzleButton.Text = 'Nozzle';
            app.DogButton = uibutton(app.MenuTab, 'push');
            app.DogButton.ButtonPushedFcn = createCallbackFcn(app, @DogButtonPushed, true);
            app.DogButton.Text = 'Dog';
            app.Image = uiimage(app.MenuTab);
            app.Image.ImageSource = 'OLVT.png';
            app.WorkingLabel = uilabel(app.MenuTab);
            app.WorkingLabel.Text = 'Working?';
            app.YesLabel = uilabel(app.MenuTab);
            app.YesLabel.Text = 'Yes :)';
            app.IworkedhardLabel = uilabel(app.MenuTab);
            app.IworkedhardLabel.Text = 'I worked hard';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LowerXBoundEditFieldLabel = uilabel(app.PlotTab);
            app.LowerXBoundEditFieldLabel.Text = 'Lower X Bound';
            app.LowerXBoundEditField = uieditfield(app.PlotTab, 'numeric');
            app.LowerXBoundEditField.ValueChangedFcn = createCallbackFcn(app, @LowerXBoundEditFieldValueChanged, true);
            app.LowerYBoundEditFieldLabel = uilabel(app.PlotTab);
            app.LowerYBoundEditFieldLabel.Text = 'Lower Y Bound';
            app.LowerYBoundEditField = uieditfield(app.PlotTab, 'numeric');
            app.LowerYBoundEditField.ValueChangedFcn = createCallbackFcn(app, @LowerYBoundEditFieldValueChanged, true);
            app.TitleEditFieldLabel = uilabel(app.PlotTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.PlotTab, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.csvFileEditFieldLabel = uilabel(app.PlotTab);
            app.csvFileEditFieldLabel.Text = '.csv File';
            app.csvFileEditField = uieditfield(app.PlotTab, 'text');
            app.csvFileEditField.ValueChangedFcn = createCallbackFcn(app, @csvFileEditFieldValueChanged, true);
            app.UpperXBoundEditFieldLabel = uilabel(app.PlotTab);
            app.UpperXBoundEditFieldLabel.Text = 'Upper X Bound';
            app.UpperXBoundEditField = uieditfield(app.PlotTab, 'numeric');
            app.UpperXBoundEditField.ValueChangedFcn = createCallbackFcn(app, @UpperXBoundEditFieldValueChanged, true);
            app.UpperYBoundEditFieldLabel = uilabel(app.PlotTab);
            app.UpperYBoundEditFieldLabel.Text = 'Upper Y Bound';
            app.UpperYBoundEditField = uieditfield(app.PlotTab, 'numeric');
            app.UpperYBoundEditField.ValueChangedFcn = createCallbackFcn(app, @UpperYBoundEditFieldValueChanged, true);
            app.PlottedValueDropDownLabel = uilabel(app.PlotTab);
            app.PlottedValueDropDownLabel.Text = 'Plotted Value';
            app.PlottedValueDropDown = uidropdown(app.PlotTab);
            app.PlottedValueDropDown.Items = {'______', 'Thrust (kN)', 'Thrust (lbf)', ''};
            app.PlottedValueDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottedValueDropDownValueChanged, true);
            app.PlottedValueDropDown.Value = '______';
            app.PlotButton_2 = uibutton(app.PlotTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.DefaultBoundsCheckBox = uicheckbox(app.PlotTab);
            app.DefaultBoundsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultBoundsCheckBoxValueChanged, true);
            app.DefaultBoundsCheckBox.Text = 'Default Bounds';
            app.XValueEditFieldLabel = uilabel(app.PlotTab);
            app.XValueEditFieldLabel.Text = 'X Value';
            app.XValueEditField = uieditfield(app.PlotTab, 'numeric');
            app.XValueEditField.ValueChangedFcn = createCallbackFcn(app, @XValueEditFieldValueChanged, true);
            app.YValueEditFieldLabel = uilabel(app.PlotTab);
            app.YValueEditFieldLabel.Text = 'Y Value';
            app.YValueEditField = uieditfield(app.PlotTab, 'numeric');
            app.MaxValueEditFieldLabel = uilabel(app.PlotTab);
            app.MaxValueEditFieldLabel.Text = 'Max Value';
            app.MaxValueEditField = uieditfield(app.PlotTab, 'numeric');
            app.MinValueEditFieldLabel = uilabel(app.PlotTab);
            app.MinValueEditFieldLabel.Text = 'Min Value';
            app.MinValueEditField = uieditfield(app.PlotTab, 'numeric');
            app.PlotsLabel = uilabel(app.PlotTab);
            app.PlotsLabel.Text = 'Plots';
            app.NozzleTab = uitab(app.TabGroup);
            app.NozzleTab.Title = 'Nozzle';
            app.OptimalThrustNEditFieldLabel = uilabel(app.NozzleTab);
            app.OptimalThrustNEditFieldLabel.Text = 'Optimal Thrust (N)';
            app.OptimalThrustNEditField = uieditfield(app.NozzleTab, 'numeric');
            app.StandardAirPressurePaEditFieldLabel = uilabel(app.NozzleTab);
            app.StandardAirPressurePaEditFieldLabel.Text = 'Standard Air Pressure (Pa)';
            app.StandardAirPressurePaEditField = uieditfield(app.NozzleTab, 'numeric');
            app.ThroatAirPressurePaEditFieldLabel = uilabel(app.NozzleTab);
            app.ThroatAirPressurePaEditFieldLabel.Text = 'Throat Air Pressure (Pa)';
            app.ThroatAirPressurePaEditField = uieditfield(app.NozzleTab, 'numeric');
            app.HeatCapacityRatioGammaEditFieldLabel = uilabel(app.NozzleTab);
            app.HeatCapacityRatioGammaEditFieldLabel.Text = 'Heat Capacity Ratio (Gamma)';
            app.HeatCapacityRatioGammaEditField = uieditfield(app.NozzleTab, 'numeric');
            app.NozzleCalculationsLabel = uilabel(app.NozzleTab);
            app.NozzleCalculationsLabel.Text = 'Nozzle Calculations';
            app.OutputLabel = uilabel(app.NozzleTab);
            app.OutputLabel.Text = 'Output';
            app.CalculateAreasButton = uibutton(app.NozzleTab, 'state');
            app.CalculateAreasButton.ValueChangedFcn = createCallbackFcn(app, @CalculateAreasButtonValueChanged, true);
            app.CalculateAreasButton.Text = 'Calculate Areas';
            app.ChokeAream2EditFieldLabel = uilabel(app.NozzleTab);
            app.ChokeAream2EditFieldLabel.Text = 'Choke Area (m^2)';
            app.ChokeAream2EditField = uieditfield(app.NozzleTab, 'text');
            app.ExitAream2EditFieldLabel = uilabel(app.NozzleTab);
            app.ExitAream2EditFieldLabel.Text = 'Exit Area (m^2)';
            app.ExitAream2EditField = uieditfield(app.NozzleTab, 'text');
            app.MachNumberatExitEditFieldLabel = uilabel(app.NozzleTab);
            app.MachNumberatExitEditFieldLabel.Text = 'Mach Number at Exit';
            app.MachNumberatExitEditField = uieditfield(app.NozzleTab, 'numeric');
            app.CalculateExpansionRatioButton = uibutton(app.NozzleTab, 'state');
            app.CalculateExpansionRatioButton.ValueChangedFcn = createCallbackFcn(app, @CalculateExpansionRatioButtonValueChanged, true);
            app.CalculateExpansionRatioButton.Text = 'Calculate Expansion Ratio';
            app.ExpansionRatioEditFieldLabel = uilabel(app.NozzleTab);
            app.ExpansionRatioEditFieldLabel.Text = 'Expansion Ratio';
            app.ExpansionRatioEditField = uieditfield(app.NozzleTab, 'text');
            app.DogTab = uitab(app.TabGroup);
            app.DogTab.Title = 'Dog';
            app.RadiusofChargeWellEditFieldLabel = uilabel(app.DogTab);
            app.RadiusofChargeWellEditFieldLabel.Text = 'Radius of Charge Well';
            app.RadiusofChargeWellEditField = uieditfield(app.DogTab, 'numeric');
            app.HeightofChargeWellEditFieldLabel = uilabel(app.DogTab);
            app.HeightofChargeWellEditFieldLabel.Text = 'Height of Charge Well';
            app.HeightofChargeWellEditField = uieditfield(app.DogTab, 'numeric');
            app.NumberofRocketGrainsEditFieldLabel = uilabel(app.DogTab);
            app.NumberofRocketGrainsEditFieldLabel.Text = 'Number of Rocket Grains';
            app.NumberofRocketGrainsEditField = uieditfield(app.DogTab, 'numeric');
            app.RunButton = uibutton(app.DogTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Button = uibutton(app.DogTab, 'push');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.DatePickerLabel = uilabel(app.Tab3);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.Tab3);
            app.Button2 = uibutton(app.Tab3, 'push');
            app.Button2.Text = 'Button2';
            app.PLEASELabel = uilabel(app.Tab3);
            app.PLEASELabel.Text = 'PLEASE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PropulsionApp_AlphaVersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end